classdef SyringePumpControlBoard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        RecordingLamp                matlab.ui.control.Lamp
        RecordingLampLabel           matlab.ui.control.Label
        SaveDataButton               matlab.ui.control.StateButton
        Label_6                      matlab.ui.control.Label
        AutosaveDataCheckBox         matlab.ui.control.CheckBox
        MatchStrokeVolumesCheckBox   matlab.ui.control.CheckBox
        mLEditField2                 matlab.ui.control.NumericEditField
        mLEditField_2Label           matlab.ui.control.Label
        Syringe2StrokeVolumePerPocketSlider  matlab.ui.control.Slider
        Syringe2StrokeVolumePerPocketSliderLabel  matlab.ui.control.Label
        RunButton                    matlab.ui.control.StateButton
        msEditField                  matlab.ui.control.NumericEditField
        msEditFieldLabel             matlab.ui.control.Label
        HzEditField                  matlab.ui.control.NumericEditField
        HzEditFieldLabel             matlab.ui.control.Label
        mLEditField1                 matlab.ui.control.NumericEditField
        mLEditFieldLabel             matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        noteAddArduinocodetorecognizetimeoffsetLabel  matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        noteTimeoffsetshouldbebetweenPeriodandPeriodinmsLabel  matlab.ui.control.Label
        Syringe1TimeOffsetSlider     matlab.ui.control.Slider
        Syringe1TimeOffsetLabel      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        CycleFrequencySlider         matlab.ui.control.Slider
        CycleFrequencySliderLabel    matlab.ui.control.Label
        FluidicControlBoardOperatingPanelLabel  matlab.ui.control.Label
        Syringe1StrokeVolumePerPocketSlider  matlab.ui.control.Slider
        SyringeStrokeVolumemLLabel   matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        ShutoffLamp                  matlab.ui.control.Lamp
        ShutoffLampLabel             matlab.ui.control.Label
        WarningLamp                  matlab.ui.control.Lamp
        WarningLampLabel             matlab.ui.control.Label
        ActuatorDutyCycleGauge       matlab.ui.control.LinearGauge
        ActuatorDutyCycleGaugeLabel  matlab.ui.control.Label
        UIAxesPosition2              matlab.ui.control.UIAxes
        UIAxesPressure               matlab.ui.control.UIAxes
        UIAxesPosition1              matlab.ui.control.UIAxes
        ContextMenu                  matlab.ui.container.ContextMenu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        sro % Serial port object
        saveFile % Whether to save data or not
        autosave % Whether to automatically save data when the FCB runs
        fileName % file name to save data to
        count = 0 %  number of data points saved
        
        %plotting
        pos_inputs = struct('act1', [], 'act2', []) % Animated desired position lines
        pos_actuals = struct('act1', [], 'act2', []) % Animated actual position lines
        pressures = struct('act1', [], 'act2', []) % Animated pressure lines
        

        % Running Parameters
        matchStrokeVolumes = true;
        strokeVolumesPerPocket = [30; 30]; % mL per pocket that is sent from the 
        cycleFrequency = 1; % Frequency at which the actualtors cycle.
        timeOffset = 0; % Delay between the actuators cycling
        running = true; % Whether the actuators are running

        % Duty Cycle Monitoring
        DutyCycle = struct('Percentage', 0, 'Warning', false, 'Shutoff', false); % Fields pertaining to the duty cycle of the actuator.
        % Percentage: The operating time of the actuator, represented as a
        % percentage of the maximum recommended operating time (5 minutes)
        % Warning: Whether to turn on the GUI warning lamp.
        % Shutoff: Whether an automatic shutoff was triggered.

    end


    % Handle serial data
    methods (Access = public)
        
        function ReadSerial(app,src,~)
            set(app.DebugBoxTextArea, 'Value', "Count: "+ app.count);
            app.count = app.count+1;
            
            % Read data from Arduino
            data = str2num(readline(src));
            

            % Add data to file
            if app.saveFile == 1
                time = string(datetime("now","Format","HH:mm:ss.SSS"));
                writematrix([app.count time data],app.fileName,'WriteMode','append');
            end

            % Add points to graph
            NUMPOINTS = 200;
            addpoints(app.pos_inputs.act1,app.count,data(2));
            addpoints(app.pos_actuals.act1,app.count,data(3));
            addpoints(app.pressures.act1,app.count,data(4));

            % Update limits
            xlim(app.UIAxesPosition1,[max(app.count-NUMPOINTS,0) max(app.count,NUMPOINTS)])
            xlim(app.UIAxesPressure,[max(app.count-NUMPOINTS,0) max(app.count,NUMPOINTS)])
    
            % Draw the plot
            drawnow limitrate;
            
        end
    end
    
    methods (Access = private)
        
        function constrained = constrain(~,x,limits)
            a = limits(1);
            b = limits(2);
            constrained = max(min(x,b),a);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            clc;
            
            % Options:
app.saveFile = 0; % Enter 1 to save to csv, 0 for off

set(app.DebugBoxTextArea, 'Value', "Hello World!");


% Constants:
NUMPOINTS = 200;

% Find the Arduino serial port
% If this breaks, open device manager and find the name of the device
% under ports, then replace dev_name with the name shown
dev_name = 'Arduino Uno';
[~,res]=system('wmic path Win32_SerialPort');
ind = strfind(res,dev_name);

if (~isempty(ind))
    % If arduino found, save the name and print
matchLine = extractBetween(res, ind(1), newline);
tokens = regexp(matchLine{1}, 'COM\d+', 'match');
port_name = tokens{1};

    fprintf('Arduino is on %s\n',port_name);
    % Attempt to open the port
    try
        % Connect to the port with a 9600 baud rate
        app.sro = serialport(port_name,9600);

        % Configure CallbackFunction for receiving serial data
        configureCallback(app.sro,"terminator",@(src, event) ReadSerial(app,src,event));

        set(app.DebugBoxTextArea, 'Value', app.sro.Port);
    catch err
        % If connection fails, print why and exit
        fprintf('%s\n%s\n',err.identifier,err.message);
        return
    end
else
    fprintf('COM-port not found\n');
    return
end 


% Flush the serial port
flush(app.sro)

% Actuator 1
% input position line 
app.pos_inputs.act1 = animatedline(app.UIAxesPosition1,'Marker','o','MarkerSize',3,'Color','g','MaximumNumPoints',NUMPOINTS+1);

% Create actual position line
app.pos_actuals.act1 = animatedline(app.UIAxesPosition1,'Marker','o','MarkerSize',3,'Color','b', 'MaximumNumPoints',NUMPOINTS+1);
xlim(app.UIAxesPosition1,[0,NUMPOINTS])
ylim(app.UIAxesPosition1,[-2,6])
ylabel(app.UIAxesPosition1,"Position(cm)")
legend(app.UIAxesPosition1,'Input', 'Actual')

% Create pressure tile
app.pressures.act1 = animatedline(app.UIAxesPressure,'Marker','o','MarkerSize',3,'MaximumNumPoints',NUMPOINTS+1);
xlim(app.UIAxesPressure,[0,NUMPOINTS])
ylim(app.UIAxesPressure,[-100, 300])
ylabel(app.UIAxesPressure,"Pressure (kPa)")

% Start counter of the number of readings
app.count = 0;

% start = tic;


% Begin readings
while false % Run this loop until user closes window
    %{
    % Read data from Arduino
    data = str2num(readline(sro));
    count = count+1;

    % Add data to file
    if app.saveFile == 1
        time = string(datetime("now","Format","HH:mm:ss.SSS"));
        writematrix([count time data],app.fileName,'WriteMode','append');
    end

    % Add points to graph
    addpoints(pos_input,count,data(2));
    addpoints(pos_act,count,data(3));
    addpoints(pressure,count,data(4));

    % Update limits
    %xlim(app.UIAxesPosition1, pos_input_ax,[max(count-NUMPOINTS,0) max(count,NUMPOINTS)])
    %xlim(app.UIAxesPressure,pressure_ax,[max(count-NUMPOINTS,0) max(count,NUMPOINTS)])
    xlim(app.UIAxesPosition1,[max(count-NUMPOINTS,0) max(count,NUMPOINTS)])
    xlim(app.UIAxesPressure,[max(count-NUMPOINTS,0) max(count,NUMPOINTS)])
    
    % Draw the plot
    drawnow limitrate;
    %}

end
        end

        % Value changed function: MatchStrokeVolumesCheckBox
        function MatchStrokeVolumesCheckBoxValueChanged(app, event)
            app.matchStrokeVolumes = app.MatchStrokeVolumesCheckBox.Value;
        end

        % Value changed function: Syringe1StrokeVolumePerPocketSlider
        function Syringe1StrokeVolumePerPocketSliderValueChanged(app, event)
            % Set value of stroke volume for actuator 1
            app.strokeVolumesPerPocket(1) = round(app.Syringe1StrokeVolumePerPocketSlider.Value,1);

            % If the values of actuator 1 and 2 are set to match, match
            % them.
            if (app.matchStrokeVolumes && app.strokeVolumesPerPocket(1) ~= app.strokeVolumesPerPocket(2))
                app.Syringe2StrokeVolumePerPocketSlider.Value = app.strokeVolumesPerPocket(1);
                Syringe2StrokeVolumePerPocketSliderValueChanged(app,event);
            end
            
            % Match the text box's value to the slider
            app.mLEditField1.Value = app.strokeVolumesPerPocket(1);

            % Send stroke volume command to actuator
            writeline(app.sro,"a 1");
            writeline(app.sro,"v " + 2*app.strokeVolumesPerPocket(1));
        end

        % Value changed function: mLEditField1
        function mLEditField1ValueChanged(app, event)
            % Change the value of the slider. Round and constrain the
            % input.
            app.Syringe1StrokeVolumePerPocketSlider.Value = constrain(app, round(app.mLEditField1.Value,1), app.Syringe1StrokeVolumePerPocketSlider.Limits);
            % Call the callback function, as if the user changed the
            % slider.
            Syringe1StrokeVolumePerPocketSliderValueChanged(app,event);
        end

        % Value changed function: Syringe2StrokeVolumePerPocketSlider
        function Syringe2StrokeVolumePerPocketSliderValueChanged(app, event)
            % Set value of stroke volume for actuator 2
            app.strokeVolumesPerPocket(2) = round(app.Syringe2StrokeVolumePerPocketSlider.Value,1);

            % If the values of actuator 1 and 2 are set to match, match
            % them.
            if (app.matchStrokeVolumes && app.strokeVolumesPerPocket(1) ~= app.strokeVolumesPerPocket(2))
                app.Syringe1StrokeVolumePerPocketSlider.Value = app.strokeVolumesPerPocket(2);
                Syringe1StrokeVolumePerPocketSliderValueChanged(app,event);
            end
            
            % Match the text box's value to the slider
            app.mLEditField2.Value = app.strokeVolumesPerPocket(2);

            % Send stroke volume command to actuator
            writeline(app.sro,"a 2");
            writeline(app.sro,"v " + 2*app.strokeVolumesPerPocket(2));
        end

        % Value changed function: mLEditField2
        function mLEditField2ValueChanged(app, event)
            % Change the value of the slider. Round and constrain the
            % input.
            app.Syringe2StrokeVolumePerPocketSlider.Value = constrain(app, round(app.mLEditField2.Value,1), app.Syringe2StrokeVolumePerPocketSlider.Limits);
            % Call the callback function, as if the user changed the
            % slider.
            Syringe2StrokeVolumePerPocketSliderValueChanged(app,event);
        end

        % Value changed function: CycleFrequencySlider
        function CycleFrequencySliderValueChanged(app, event)
            % Set value of cycle frequency
            app.cycleFrequency = round(app.CycleFrequencySlider.Value,1);
            
            % Match the text box's value to the slider
            app.HzEditField.Value = app.cycleFrequency;

            % Change bounds of time offset based off of cycle frequency.
            period = 1/max(app.cycleFrequency,0.01)*1000;
            upperLimit = round(period/2/50)*50;
            lowerLimit = -round(period/2/50)*50;
            newLimits = [lowerLimit,upperLimit];
            app.Syringe1TimeOffsetSlider.Value = constrain(app, app.Syringe1TimeOffsetSlider.Value, newLimits);
            app.Syringe1TimeOffsetSlider.Limits = newLimits;

            % Send cycle frequency command to actuator
            writeline(app.sro,"f " + app.cycleFrequency);
        end

        % Value changed function: HzEditField
        function HzEditFieldValueChanged(app, event)
            % Change the value of the slider. Round and constrain the
            % input.
            app.CycleFrequencySlider.Value = constrain(app, round(app.HzEditField.Value,1), app.CycleFrequencySlider.Limits);
            % Call the callback function, as if the user changed the
            % slider.
            CycleFrequencySliderValueChanged(app,event);
            
        end

        % Value changed function: Syringe1TimeOffsetSlider
        function Syringe1TimeOffsetSliderValueChanged(app, event)
            % Set value of time offset
            app.timeOffset = round(app.Syringe1TimeOffsetSlider.Value,1);
            
            % Match the text box's value to the slider
            app.msEditField.Value = app.timeOffset;

            % Send time offset command to actuator
            writeline(app.sro,"t " + app.timeOffset);
        end

        % Value changed function: msEditField
        function msEditFieldValueChanged(app, event)
            % Change the value of the slider. Round and constrain the
            % input.
            app.Syringe1TimeOffsetSlider.Value = constrain(app, round(app.msEditField.Value,1), app.Syringe1TimeOffsetSlider.Limits);
            % Call the callback function, as if the user changed the
            % slider.
            Syringe1TimeOffsetSliderValueChanged(app,event);
        end

        % Value changed function: AutosaveDataCheckBox
        function AutosaveDataCheckBoxValueChanged(app, event)
            app.autosave = app.AutosaveDataCheckBox.Value;
        end

        % Value changed function: SaveDataButton
        function SaveDataButtonValueChanged(app, event)
            value = app.SaveDataButton.Value;
            % CSV Data saving
            if value == 1
                app.saveFile = true;
                app.RecordingLamp.Color = [1,0,0];
                app.fileName = "FCD_Data_"+string(datetime("now","Format","uuuuMMdd_HH_mm_ss"))+".csv";
                writematrix(["Count" "Matlab Time (s)" "Arduino Time (ms)" "Input Position (cm)" "Actual Position (cm)" "Pressure (kPa)"],app.fileName)
            else
                app.saveFile = false;
                app.RecordingLamp.Color = [0,0,0];
            end
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            value = app.RunButton.Value;
            
            if app.autosave
                app.SaveDataButton.Value = value;
                SaveDataButtonValueChanged(app,event);
            end

            if value
                app.running = true;
                app.RunButton.Text = "Stop";
                app.RunButton.BackgroundColor = [0.64,0.08,0.18];
                writeline(app.sro,"r");
            else
                app.running = false;
                app.RunButton.Text = "Run";
                app.RunButton.BackgroundColor = [0.37,0.66,0.19];
                writeline(app.sro,"s");
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {321, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = 'note: add arduino code to handle a cycle frequency of 0. probably just override "running" to be false.';
            app.SyringeStrokeVolumemLLabel = uilabel(app.LeftPanel);
            app.SyringeStrokeVolumemLLabel.Text = {'Syringe 1 Stroke '; 'Volume Per Pocket'};
            app.Syringe1StrokeVolumePerPocketSlider = uislider(app.LeftPanel);
            app.Syringe1StrokeVolumePerPocketSlider.Limits = [10 40];
            app.Syringe1StrokeVolumePerPocketSlider.ValueChangedFcn = createCallbackFcn(app, @Syringe1StrokeVolumePerPocketSliderValueChanged, true);
            app.Syringe1StrokeVolumePerPocketSlider.Value = 30;
            app.FluidicControlBoardOperatingPanelLabel = uilabel(app.LeftPanel);
            app.FluidicControlBoardOperatingPanelLabel.Text = {'Fluidic Control Board'; 'Operating Panel'};
            app.CycleFrequencySliderLabel = uilabel(app.LeftPanel);
            app.CycleFrequencySliderLabel.Text = 'Cycle Frequency';
            app.CycleFrequencySlider = uislider(app.LeftPanel);
            app.CycleFrequencySlider.Limits = [0 2.5];
            app.CycleFrequencySlider.ValueChangedFcn = createCallbackFcn(app, @CycleFrequencySliderValueChanged, true);
            app.CycleFrequencySlider.Value = 1;
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'note: add arduino code to handle a cycle frequency of 0. probably just override "running" to be false.';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = 'note: add arduino code to handle a cycle frequency of 0. probably just override "running" to be false.';
            app.Syringe1TimeOffsetLabel = uilabel(app.LeftPanel);
            app.Syringe1TimeOffsetLabel.Text = {'Syringe 1'; 'Time Offset'};
            app.Syringe1TimeOffsetSlider = uislider(app.LeftPanel);
            app.Syringe1TimeOffsetSlider.Limits = [-1000 1000];
            app.Syringe1TimeOffsetSlider.ValueChangedFcn = createCallbackFcn(app, @Syringe1TimeOffsetSliderValueChanged, true);
            app.noteTimeoffsetshouldbebetweenPeriodandPeriodinmsLabel = uilabel(app.LeftPanel);
            app.noteTimeoffsetshouldbebetweenPeriodandPeriodinmsLabel.Text = 'note: Time offset should be between +Period and -Period, in ms.';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = 'note: Add arduino code to recognize which actuator is being edited, for time offset';
            app.noteAddArduinocodetorecognizetimeoffsetLabel = uilabel(app.LeftPanel);
            app.noteAddArduinocodetorecognizetimeoffsetLabel.Text = 'note: Add Arduino code to recognize time offset';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = 'note: Have the runtime gauge display remaining time in minutes, and slowly refresh when not in use.';
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = 'note: There need to be numeric displays that show the currnet values of parameters.';
            app.mLEditFieldLabel = uilabel(app.LeftPanel);
            app.mLEditFieldLabel.Text = 'mL';
            app.mLEditField1 = uieditfield(app.LeftPanel, 'numeric');
            app.mLEditField1.ValueChangedFcn = createCallbackFcn(app, @mLEditField1ValueChanged, true);
            app.mLEditField1.Placeholder = 'Value';
            app.mLEditField1.Value = 30;
            app.HzEditFieldLabel = uilabel(app.LeftPanel);
            app.HzEditFieldLabel.Text = 'Hz';
            app.HzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HzEditField.ValueChangedFcn = createCallbackFcn(app, @HzEditFieldValueChanged, true);
            app.HzEditField.Placeholder = 'Value';
            app.HzEditField.Value = 1;
            app.msEditFieldLabel = uilabel(app.LeftPanel);
            app.msEditFieldLabel.Text = 'ms';
            app.msEditField = uieditfield(app.LeftPanel, 'numeric');
            app.msEditField.ValueChangedFcn = createCallbackFcn(app, @msEditFieldValueChanged, true);
            app.msEditField.Placeholder = 'Value';
            app.RunButton = uibutton(app.LeftPanel, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.Syringe2StrokeVolumePerPocketSliderLabel = uilabel(app.LeftPanel);
            app.Syringe2StrokeVolumePerPocketSliderLabel.Text = {'Syringe 2 Stroke '; 'Volume Per Pocket'};
            app.Syringe2StrokeVolumePerPocketSlider = uislider(app.LeftPanel);
            app.Syringe2StrokeVolumePerPocketSlider.Limits = [10 40];
            app.Syringe2StrokeVolumePerPocketSlider.ValueChangedFcn = createCallbackFcn(app, @Syringe2StrokeVolumePerPocketSliderValueChanged, true);
            app.Syringe2StrokeVolumePerPocketSlider.Value = 30;
            app.mLEditField_2Label = uilabel(app.LeftPanel);
            app.mLEditField_2Label.Text = 'mL';
            app.mLEditField2 = uieditfield(app.LeftPanel, 'numeric');
            app.mLEditField2.ValueChangedFcn = createCallbackFcn(app, @mLEditField2ValueChanged, true);
            app.mLEditField2.Placeholder = 'Value';
            app.mLEditField2.Value = 30;
            app.MatchStrokeVolumesCheckBox = uicheckbox(app.LeftPanel);
            app.MatchStrokeVolumesCheckBox.ValueChangedFcn = createCallbackFcn(app, @MatchStrokeVolumesCheckBoxValueChanged, true);
            app.MatchStrokeVolumesCheckBox.Text = 'Match Stroke Volumes';
            app.MatchStrokeVolumesCheckBox.Value = true;
            app.AutosaveDataCheckBox = uicheckbox(app.LeftPanel);
            app.AutosaveDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutosaveDataCheckBoxValueChanged, true);
            app.AutosaveDataCheckBox.Text = {'Autosave'; 'Data'};
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = '';
            app.SaveDataButton = uibutton(app.LeftPanel, 'state');
            app.SaveDataButton.ValueChangedFcn = createCallbackFcn(app, @SaveDataButtonValueChanged, true);
            app.SaveDataButton.Text = 'Save Data';
            app.RecordingLampLabel = uilabel(app.LeftPanel);
            app.RecordingLampLabel.Text = 'Recording';
            app.RecordingLamp = uilamp(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxesPosition1 = uiaxes(app.RightPanel);
            title(app.UIAxesPosition1, 'Syringe 1 Status')
            xlabel(app.UIAxesPosition1, 'Time (s)')
            ylabel(app.UIAxesPosition1, 'Position (cm)')
            zlabel(app.UIAxesPosition1, 'Z')
            app.UIAxesPressure = uiaxes(app.RightPanel);
            title(app.UIAxesPressure, 'Syringe Pressures')
            xlabel(app.UIAxesPressure, 'Time (s)')
            ylabel(app.UIAxesPressure, 'Pressure (kPa)')
            zlabel(app.UIAxesPressure, 'Z')
            app.UIAxesPosition2 = uiaxes(app.RightPanel);
            title(app.UIAxesPosition2, 'Syringe 2 Status')
            xlabel(app.UIAxesPosition2, 'Time (s)')
            ylabel(app.UIAxesPosition2, 'Position (cm)')
            zlabel(app.UIAxesPosition2, 'Z')
            app.ActuatorDutyCycleGaugeLabel = uilabel(app.RightPanel);
            app.ActuatorDutyCycleGaugeLabel.Text = 'Actuator Duty Cycle';
            app.ActuatorDutyCycleGauge = uigauge(app.RightPanel, 'linear');
            app.ActuatorDutyCycleGauge.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.WarningLampLabel = uilabel(app.RightPanel);
            app.WarningLampLabel.Text = 'Warning';
            app.WarningLamp = uilamp(app.RightPanel);
            app.ShutoffLampLabel = uilabel(app.RightPanel);
            app.ShutoffLampLabel.Text = 'Shutoff';
            app.ShutoffLamp = uilamp(app.RightPanel);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.Syringe1TimeOffsetSlider.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SyringePumpControlBoard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end